classdef Curve_Length < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExploratoryMathematicsUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DIntegralCurveLengthTab         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        PartitionsSliderLabel           matlab.ui.control.Label
        PartitionsSlider                matlab.ui.control.Slider
        MaxPartitionsEditFieldLabel     matlab.ui.control.Label
        MaxPartitionsEditField          matlab.ui.control.EditField
        EstimationLabel                 matlab.ui.control.Label
        Estimation                      matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        Error                           matlab.ui.control.Label
        ExactLabel                      matlab.ui.control.Label
        Exact                           matlab.ui.control.Label
        FxEditFieldLabel                matlab.ui.control.Label
        FxEditField                     matlab.ui.control.EditField
        xLimitsEditFieldLabel           matlab.ui.control.Label
        xLimitsEditField                matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CurrentValue  % slider value
        f  % function option
        ExactValue % exa
        Limits % Description
    end
    
    methods (Access = private)
        
        function UpdateGraph(app)
            x = linspace(app.Limits(1),app.Limits(2),app.CurrentValue);
            x_ = linspace(app.Limits(1),app.Limits(2),app.CurrentValue*100);
            delta_x = x(2) - x(1);
            try
                y = app.f(x);
                y_ = -diff(y);
                app.FxEditField.BackgroundColor = [1 1 1];
                estimation = sum(sqrt(delta_x.^2+y_.^2));
                app.Estimation.Text = sprintf('%0.3f',estimation);
                app.Error.Text = sprintf('%0.3f',abs(app.ExactValue - estimation));
                xlim(app.UIAxes,app.Limits)
                plot(app.UIAxes,x,y,"LineWidth",1);
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x_,app.f(x_))
                hold(app.UIAxes,"off")
            catch
                app.FxEditField.BackgroundColor = [1 .6 .6];
            end
        end
        
        function UpdateExactValue(app)
            curve = @(x) sqrt(1+(app.f(x)).^2);
            app.ExactValue = integral(curve,app.Limits(1),app.Limits(2));
            app.Exact.Text = sprintf('%0.3f',app.ExactValue);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CurrentValue = ceil(app.PartitionsSlider.Value);
            app.f = str2func(strcat('@(x)',app.FxEditField.Value));
            app.Limits = [-pi,pi];
            UpdateExactValue(app);
            UpdateGraph(app)
        end

        % Value changing function: PartitionsSlider
        function SliderUpdate(app, event)
            changingValue = ceil(event.Value);
            app.CurrentValue = changingValue;
            UpdateGraph(app)
        end

        % Value changed function: MaxPartitionsEditField
        function MaxChanged(app, event)
            value = app.MaxPartitionsEditField.Value;
            numericVal = str2double(value);
            if isnan(numericVal)
                app.MaxPartitionsEditField.BackgroundColor=[1 .6 .6];
                return
            else
                app.MaxPartitionsEditField.BackgroundColor=[1 1 1];
            end
            app.PartitionsSlider.Limits = [2,numericVal];
            UpdateGraph(app)
        end

        % Value changed function: FxEditField
        function FxEditFieldValueChanged(app, event)
            value = strcat('@(x)',app.FxEditField.Value);
            try
                app.f = str2func(value);
                UpdateExactValue(app)
                UpdateGraph(app);
            catch
                app.FxEditField.BackgroundColor = [1 .6 .6];
            end
        end

        % Value changed function: xLimitsEditField
        function xLimitsEditFieldValueChanged(app, event)
            value = app.xLimitsEditField.Value;
            value((value == ']') | (value =='[')) = '';
            lims = split(value,',');
            lims = str2double(lims);
            if(length(lims) ~= 2 ||...
                any(isnan(lims)) ||...
                abs(max(lims)-min(lims))<.5)
                app.xLimitsEditField.BackgroundColor = [1 .6 .6];
                return
            end
            app.Limits = [min(lims),max(lims)];
            UpdateExactValue(app)
            UpdateGraph(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExploratoryMathematicsUIFigure = uifigure('Visible', 'off');
            app.ExploratoryMathematicsUIFigure.Name = 'Exploratory Mathematics';
            app.TabGroup = uitabgroup(app.ExploratoryMathematicsUIFigure);
            app.DIntegralCurveLengthTab = uitab(app.TabGroup);
            app.DIntegralCurveLengthTab.Title = '1D Integral Curve Length';
            app.UIAxes = uiaxes(app.DIntegralCurveLengthTab);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PartitionsSliderLabel = uilabel(app.DIntegralCurveLengthTab);
            app.PartitionsSliderLabel.Text = 'Partitions:';
            app.PartitionsSlider = uislider(app.DIntegralCurveLengthTab);
            app.PartitionsSlider.Limits = [2 100];
            app.PartitionsSlider.ValueChangingFcn = createCallbackFcn(app, @SliderUpdate, true);
            app.PartitionsSlider.Value = 25;
            app.MaxPartitionsEditFieldLabel = uilabel(app.DIntegralCurveLengthTab);
            app.MaxPartitionsEditFieldLabel.Text = 'Max Partitions:';
            app.MaxPartitionsEditField = uieditfield(app.DIntegralCurveLengthTab, 'text');
            app.MaxPartitionsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxChanged, true);
            app.MaxPartitionsEditField.Value = '100';
            app.EstimationLabel = uilabel(app.DIntegralCurveLengthTab);
            app.EstimationLabel.Text = 'Estimation:';
            app.Estimation = uilabel(app.DIntegralCurveLengthTab);
            app.Estimation.Text = 'NaN';
            app.ErrorLabel = uilabel(app.DIntegralCurveLengthTab);
            app.ErrorLabel.Text = 'Error:';
            app.Error = uilabel(app.DIntegralCurveLengthTab);
            app.Error.Text = 'NaN';
            app.ExactLabel = uilabel(app.DIntegralCurveLengthTab);
            app.ExactLabel.Text = 'Exact:';
            app.Exact = uilabel(app.DIntegralCurveLengthTab);
            app.Exact.Text = 'NaN';
            app.FxEditFieldLabel = uilabel(app.DIntegralCurveLengthTab);
            app.FxEditFieldLabel.Text = 'F(x)=';
            app.FxEditField = uieditfield(app.DIntegralCurveLengthTab, 'text');
            app.FxEditField.ValueChangedFcn = createCallbackFcn(app, @FxEditFieldValueChanged, true);
            app.FxEditField.Value = 'cos(x)';
            app.xLimitsEditFieldLabel = uilabel(app.DIntegralCurveLengthTab);
            app.xLimitsEditFieldLabel.Text = 'x Limits:';
            app.xLimitsEditField = uieditfield(app.DIntegralCurveLengthTab, 'text');
            app.xLimitsEditField.ValueChangedFcn = createCallbackFcn(app, @xLimitsEditFieldValueChanged, true);
            app.xLimitsEditField.Value = '[-3.14,3.14]';
            % Show the figure after all components are created
            app.ExploratoryMathematicsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Curve_Length

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExploratoryMathematicsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExploratoryMathematicsUIFigure)
        end
    end
end