classdef Exploratory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        DIntegralTab                 matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        PartitionsSliderLabel        matlab.ui.control.Label
        PartitionsSlider             matlab.ui.control.Slider
        FunctionDropDownLabel        matlab.ui.control.Label
        FunctionDropDown             matlab.ui.control.DropDown
        MaxPartitionsEditFieldLabel  matlab.ui.control.Label
        MaxPartitionsEditField       matlab.ui.control.EditField
        EstimationLabel              matlab.ui.control.Label
        Estimation                   matlab.ui.control.Label
        ErrorLabel                   matlab.ui.control.Label
        Error                        matlab.ui.control.Label
        ExactLabel                   matlab.ui.control.Label
        Exact                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        CurrentValue  % slider value
        f  % function option
        ExactValue % exa
    end
    
    methods (Access = private)
        
        function UpdateGraph(app)
            x = linspace(-pi,pi,app.CurrentValue);
            delta_x = x(2) - x(1);
            y = app.f(x);
            estimation = sum(y)*delta_x;
            app.Estimation.Text = sprintf('%0.3f',estimation);
            app.Error.Text = sprintf('%0.3f',abs(app.ExactValue - estimation));
            
            hold(app.UIAxes,"off");
            plot(app.UIAxes,x,y);
            hold(app.UIAxes,'on');
            bar(app.UIAxes,x,y,'EdgeColor',"none","BarWidth",1);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CurrentValue = ceil(app.PartitionsSlider.Value);
            app.f = @(x) sin(x);
            app.ExactValue = integral(app.f,-pi,pi);
            app.Exact.Text = sprintf('%0.3f',app.ExactValue);
            UpdateGraph(app)
        end

        % Value changing function: PartitionsSlider
        function SliderUpdate(app, event)
            changingValue = ceil(event.Value);
            app.CurrentValue = changingValue;
            UpdateGraph(app)
        end

        % Value changed function: MaxPartitionsEditField
        function MaxChanged3(app, event)
            value = app.MaxPartitionsEditField.Value;
            numericVal = str2double(value);
            app.PartitionsSlider.Limits = [2,numericVal];
            UpdateGraph(app)
        end

        % Value changed function: FunctionDropDown
        function FunctionChanged(app, event)
            value = app.FunctionDropDown.Value;
            switch(value)
                case '0'
                    app.f = @(x) sin(x);
                case '1'
                    app.f = @(x) cos(x);
                case '2'
                    app.f = @(x) exp(x);
                case '3'
                    app.f = @(x) (0.5).*x+1;
                otherwise
                    app.f = @(x) sin(x);
            end
            app.ExactValue = integral(app.f,-pi,pi);
            app.Exact.Text = sprintf('%0.3f',app.ExactValue);
            UpdateGraph(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DIntegralTab = uitab(app.TabGroup);
            app.DIntegralTab.Title = '1D Integral';
            app.UIAxes = uiaxes(app.DIntegralTab);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PartitionsSliderLabel = uilabel(app.DIntegralTab);
            app.PartitionsSliderLabel.Text = 'Partitions:';
            app.PartitionsSlider = uislider(app.DIntegralTab);
            app.PartitionsSlider.Limits = [2 100];
            app.PartitionsSlider.ValueChangingFcn = createCallbackFcn(app, @SliderUpdate, true);
            app.PartitionsSlider.Value = 25;
            app.FunctionDropDownLabel = uilabel(app.DIntegralTab);
            app.FunctionDropDownLabel.Text = 'Function:';
            app.FunctionDropDown = uidropdown(app.DIntegralTab);
            app.FunctionDropDown.Items = {'Sin(x)', 'Cos(x)', 'Exp(x)', 'Linear'};
            app.FunctionDropDown.ItemsData = {'0', '1', '2', '3'};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionChanged, true);
            app.FunctionDropDown.Value = '0';
            app.MaxPartitionsEditFieldLabel = uilabel(app.DIntegralTab);
            app.MaxPartitionsEditFieldLabel.Text = 'Max Partitions:';
            app.MaxPartitionsEditField = uieditfield(app.DIntegralTab, 'text');
            app.MaxPartitionsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxChanged3, true);
            app.MaxPartitionsEditField.Value = '100';
            app.EstimationLabel = uilabel(app.DIntegralTab);
            app.EstimationLabel.Text = 'Estimation:';
            app.Estimation = uilabel(app.DIntegralTab);
            app.Estimation.Text = 'NaN';
            app.ErrorLabel = uilabel(app.DIntegralTab);
            app.ErrorLabel.Text = 'Error:';
            app.Error = uilabel(app.DIntegralTab);
            app.Error.Text = 'NaN';
            app.ExactLabel = uilabel(app.DIntegralTab);
            app.ExactLabel.Text = 'Exact:';
            app.Exact = uilabel(app.DIntegralTab);
            app.Exact.Text = 'NaN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exploratory

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end