classdef Integral_II_Bar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExploratoryMathematicsUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DIntegralTab                    matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        PartitionsSliderLabel           matlab.ui.control.Label
        PartitionsSlider                matlab.ui.control.Slider
        PartitionsEditFieldLabel        matlab.ui.control.Label
        PartitionsEditField             matlab.ui.control.EditField
        EstimationLabel                 matlab.ui.control.Label
        Estimation                      matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        Error                           matlab.ui.control.Label
        ExactLabel                      matlab.ui.control.Label
        Exact                           matlab.ui.control.Label
        FxyLabel                        matlab.ui.control.Label
        FxyEditField                    matlab.ui.control.EditField
        LimitsEditFieldLabel            matlab.ui.control.Label
        LimitsEditField                 matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CurrentValue  % slider value
        f  % function option
        ExactValue % exa
        Limits % Description
    end
    
    methods (Access = private)
        
        function UpdateGraph(app)
            x = linspace(app.Limits(1),app.Limits(2),app.CurrentValue);
            y = linspace(app.Limits(1),app.Limits(2),app.CurrentValue)';
            delta_x = x(2) - x(1);
            delta_y = y(2) - y(1);
            try
                z = app.f(x,y);
                app.FxyEditField.BackgroundColor = [1 1 1];
                estimation = sum(z(:))*delta_x*delta_y;
                app.Estimation.Text = sprintf('%0.3f',estimation);
                app.Error.Text = sprintf('%0.3f',abs(app.ExactValue - estimation));
                xlim(app.UIAxes,app.Limits)
                ylim(app.UIAxes,app.Limits)
                axis(app.UIAxes,'square')       
                b = bar3(app.UIAxes,y,z);
                for k = 1:length(b)
                    b(k).CData = b(k).ZData;
                    b(k).FaceColor = 'interp';
                end
            catch
                app.FxyEditField.BackgroundColor = [1 .6 .6];
            end
        end
        
        function UpdateExactValue(app)
            app.ExactValue = integral2(app.f,app.Limits(1),app.Limits(2),app.Limits(1),app.Limits(2));
            app.Exact.Text = sprintf('%0.3f',app.ExactValue);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CurrentValue = ceil(app.PartitionsSlider.Value);
            app.f = str2func(strcat('@(x,y) ',app.FxyEditField.Value));
            app.Limits = [-pi,pi];
            UpdateExactValue(app);
            UpdateGraph(app)
        end

        % Value changing function: PartitionsSlider
        function SliderUpdate(app, event)
            changingValue = ceil(event.Value);
            app.CurrentValue = changingValue;
            UpdateGraph(app)
        end

        % Value changed function: PartitionsEditField
        function MaxChanged(app, event)
            value = app.PartitionsEditField.Value;
            numericVal = str2double(value);
            if isnan(numericVal)
                app.PartitionsEditField.BackgroundColor=[1 .6 .6];
                return
            else
                app.PartitionsEditField.BackgroundColor=[1 1 1];
            end
            app.PartitionsSlider.Limits = [2,numericVal];
            UpdateGraph(app)
        end

        % Value changed function: FxyEditField
        function FxyEditFieldValueChanged(app, event)
            value = strcat('@(x,y) ',app.FxyEditField.Value);
            try
                app.f = str2func(value);
                UpdateExactValue(app)
                UpdateGraph(app);
            catch
                app.FxyEditField.BackgroundColor = [1 .6 .6];
            end
        end

        % Value changed function: LimitsEditField
        function LimitsEditFieldValueChanged(app, event)
            value = app.LimitsEditField.Value;
            value((value == ']') | (value =='[')) = '';
            lims = split(value,',');
            lims = str2double(lims);
            if(length(lims) ~= 2 ||...
                any(isnan(lims)) ||...
                abs(max(lims)-min(lims))<.5)
                app.LimitsEditField.BackgroundColor = [1 .6 .6];
                return
            end
            app.Limits = [min(lims),max(lims)];
            UpdateExactValue(app)
            UpdateGraph(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExploratoryMathematicsUIFigure = uifigure('Visible', 'off');
            app.ExploratoryMathematicsUIFigure.Name = 'Exploratory Mathematics';
            app.TabGroup = uitabgroup(app.ExploratoryMathematicsUIFigure);
            app.DIntegralTab = uitab(app.TabGroup);
            app.DIntegralTab.Title = '2D Integral';
            app.UIAxes = uiaxes(app.DIntegralTab);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PartitionsSliderLabel = uilabel(app.DIntegralTab);
            app.PartitionsSliderLabel.Text = 'Partitions:';
            app.PartitionsSlider = uislider(app.DIntegralTab);
            app.PartitionsSlider.Limits = [2 100];
            app.PartitionsSlider.ValueChangingFcn = createCallbackFcn(app, @SliderUpdate, true);
            app.PartitionsSlider.Value = 25;
            app.PartitionsEditFieldLabel = uilabel(app.DIntegralTab);
            app.PartitionsEditFieldLabel.Text = 'Partitions:';
            app.PartitionsEditField = uieditfield(app.DIntegralTab, 'text');
            app.PartitionsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxChanged, true);
            app.PartitionsEditField.Value = '100';
            app.EstimationLabel = uilabel(app.DIntegralTab);
            app.EstimationLabel.Text = 'Estimation:';
            app.Estimation = uilabel(app.DIntegralTab);
            app.Estimation.Text = 'NaN';
            app.ErrorLabel = uilabel(app.DIntegralTab);
            app.ErrorLabel.Text = 'Error:';
            app.Error = uilabel(app.DIntegralTab);
            app.Error.Text = 'NaN';
            app.ExactLabel = uilabel(app.DIntegralTab);
            app.ExactLabel.Text = 'Exact:';
            app.Exact = uilabel(app.DIntegralTab);
            app.Exact.Text = 'NaN';
            app.FxyLabel = uilabel(app.DIntegralTab);
            app.FxyLabel.Text = 'F(x y)=';
            app.FxyEditField = uieditfield(app.DIntegralTab, 'text');
            app.FxyEditField.ValueChangedFcn = createCallbackFcn(app, @FxyEditFieldValueChanged, true);
            app.FxyEditField.Value = 'cos(y)+sin(x)';
            app.LimitsEditFieldLabel = uilabel(app.DIntegralTab);
            app.LimitsEditFieldLabel.Text = 'Limits:';
            app.LimitsEditField = uieditfield(app.DIntegralTab, 'text');
            app.LimitsEditField.ValueChangedFcn = createCallbackFcn(app, @LimitsEditFieldValueChanged, true);
            app.LimitsEditField.Value = '[-3.14,3.14]';
            % Show the figure after all components are created
            app.ExploratoryMathematicsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Integral_II_Bar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExploratoryMathematicsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExploratoryMathematicsUIFigure)
        end
    end
end