classdef Integral_I_Bar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExploratoryMathematicsUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DIntegralAreaTab                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        PartitionsSliderLabel           matlab.ui.control.Label
        PartitionsSlider                matlab.ui.control.Slider
        MaxPartitionsEditFieldLabel     matlab.ui.control.Label
        MaxPartitionsEditField          matlab.ui.control.EditField
        EstimationLabel                 matlab.ui.control.Label
        Estimation                      matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        Error                           matlab.ui.control.Label
        ExactLabel                      matlab.ui.control.Label
        Exact                           matlab.ui.control.Label
        FxEditFieldLabel                matlab.ui.control.Label
        FxEditField                     matlab.ui.control.EditField
        xLimitsEditFieldLabel           matlab.ui.control.Label
        xLimitsEditField                matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CurrentValue  % slider value
        f  % function option
        ExactValue % exa
        Limits % Description
    end
    
    methods (Access = private)
        
        function UpdateGraph(app)
            x = linspace(app.Limits(1),app.Limits(2),app.CurrentValue);
            delta_x = x(2) - x(1);
            try
                y = app.f(x);
                app.FxEditField.BackgroundColor = [1 1 1];
                estimation = sum(y)*delta_x;
                app.Estimation.Text = sprintf('%0.3f',estimation);
                app.Error.Text = sprintf('%0.3f',abs(app.ExactValue - estimation));
                xlim(app.UIAxes,app.Limits)
                hold(app.UIAxes,"off");
                bar(app.UIAxes,x,y,'EdgeColor',"none","BarWidth",1);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,x,y,"LineWidth",1);
            catch
                app.FxEditField.BackgroundColor = [1 .6 .6];
            end
        end
        
        function UpdateExactValue(app)
            app.ExactValue = integral(app.f,app.Limits(1),app.Limits(2));
            app.Exact.Text = sprintf('%0.3f',app.ExactValue);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CurrentValue = ceil(app.PartitionsSlider.Value);
            app.f = str2func(strcat('@(x)',app.FxEditField.Value));
            app.Limits = [-pi,pi];
            UpdateExactValue(app);
            UpdateGraph(app)
        end

        % Value changing function: PartitionsSlider
        function SliderUpdate(app, event)
            changingValue = ceil(event.Value);
            app.CurrentValue = changingValue;
            UpdateGraph(app)
        end

        % Value changed function: MaxPartitionsEditField
        function MaxChanged(app, event)
            value = app.MaxPartitionsEditField.Value;
            numericVal = str2double(value);
            if isnan(numericVal)
                app.MaxPartitionsEditField.BackgroundColor=[1 .6 .6];
                return
            else
                app.MaxPartitionsEditField.BackgroundColor=[1 1 1];
            end
            app.PartitionsSlider.Limits = [2,numericVal];
            UpdateGraph(app)
        end

        % Value changed function: FxEditField
        function FxEditFieldValueChanged(app, event)
            value = strcat('@(x)',app.FxEditField.Value);
            try
                app.f = str2func(value);
                UpdateExactValue(app)
                UpdateGraph(app);
            catch
                app.FxEditField.BackgroundColor = [1 .6 .6];
            end
        end

        % Value changed function: xLimitsEditField
        function xLimitsEditFieldValueChanged(app, event)
            value = app.xLimitsEditField.Value;
            value((value == ']') | (value =='[')) = '';
            lims = split(value,',');
            lims = str2double(lims);
            if(length(lims) ~= 2 ||...
                any(isnan(lims)) ||...
                abs(max(lims)-min(lims))<.5)
                app.xLimitsEditField.BackgroundColor = [1 .6 .6];
                return
            end
            app.Limits = [min(lims),max(lims)];
            UpdateExactValue(app)
            UpdateGraph(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExploratoryMathematicsUIFigure = uifigure('Visible', 'off');
            app.ExploratoryMathematicsUIFigure.Name = 'Exploratory Mathematics';
            app.TabGroup = uitabgroup(app.ExploratoryMathematicsUIFigure);
            app.DIntegralAreaTab = uitab(app.TabGroup);
            app.DIntegralAreaTab.Title = '1D Integral Area';
            app.UIAxes = uiaxes(app.DIntegralAreaTab);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PartitionsSliderLabel = uilabel(app.DIntegralAreaTab);
            app.PartitionsSliderLabel.Text = 'Partitions:';
            app.PartitionsSlider = uislider(app.DIntegralAreaTab);
            app.PartitionsSlider.Limits = [2 100];
            app.PartitionsSlider.ValueChangingFcn = createCallbackFcn(app, @SliderUpdate, true);
            app.PartitionsSlider.Value = 25;
            app.MaxPartitionsEditFieldLabel = uilabel(app.DIntegralAreaTab);
            app.MaxPartitionsEditFieldLabel.Text = 'Max Partitions:';
            app.MaxPartitionsEditField = uieditfield(app.DIntegralAreaTab, 'text');
            app.MaxPartitionsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxChanged, true);
            app.MaxPartitionsEditField.Value = '100';
            app.EstimationLabel = uilabel(app.DIntegralAreaTab);
            app.EstimationLabel.Text = 'Estimation:';
            app.Estimation = uilabel(app.DIntegralAreaTab);
            app.Estimation.Text = 'NaN';
            app.ErrorLabel = uilabel(app.DIntegralAreaTab);
            app.ErrorLabel.Text = 'Error:';
            app.Error = uilabel(app.DIntegralAreaTab);
            app.Error.Text = 'NaN';
            app.ExactLabel = uilabel(app.DIntegralAreaTab);
            app.ExactLabel.Text = 'Exact:';
            app.Exact = uilabel(app.DIntegralAreaTab);
            app.Exact.Text = 'NaN';
            app.FxEditFieldLabel = uilabel(app.DIntegralAreaTab);
            app.FxEditFieldLabel.Text = 'F(x)=';
            app.FxEditField = uieditfield(app.DIntegralAreaTab, 'text');
            app.FxEditField.ValueChangedFcn = createCallbackFcn(app, @FxEditFieldValueChanged, true);
            app.FxEditField.Value = 'cos(x)';
            app.xLimitsEditFieldLabel = uilabel(app.DIntegralAreaTab);
            app.xLimitsEditFieldLabel.Text = 'x Limits:';
            app.xLimitsEditField = uieditfield(app.DIntegralAreaTab, 'text');
            app.xLimitsEditField.ValueChangedFcn = createCallbackFcn(app, @xLimitsEditFieldValueChanged, true);
            app.xLimitsEditField.Value = '[-3.14,3.14]';
            % Show the figure after all components are created
            app.ExploratoryMathematicsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Integral_I_Bar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExploratoryMathematicsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExploratoryMathematicsUIFigure)
        end
    end
end