classdef Line_Integral_Vector_Fields < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExploratoryMathematicsUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LineIntegralVectorFieldsTab     matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        PartitionsSliderLabel           matlab.ui.control.Label
        PartitionsSlider                matlab.ui.control.Slider
        MaxPartitionsEditFieldLabel     matlab.ui.control.Label
        MaxPartitionsEditField          matlab.ui.control.EditField
        EstimationLabel                 matlab.ui.control.Label
        Estimation                      matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        Error                           matlab.ui.control.Label
        BetterEstimationLabel           matlab.ui.control.Label
        Exact                           matlab.ui.control.Label
        XtEditFieldLabel                matlab.ui.control.Label
        XtEditField                     matlab.ui.control.EditField
        tLimitsEditFieldLabel           matlab.ui.control.Label
        tLimitsEditField                matlab.ui.control.EditField
        YtEditFieldLabel                matlab.ui.control.Label
        YtEditField                     matlab.ui.control.EditField
        PxyLabel                        matlab.ui.control.Label
        PxyEditField                    matlab.ui.control.EditField
        QxyEditFieldLabel               matlab.ui.control.Label
        QxyEditField                    matlab.ui.control.EditField
        FxyPxyiQxyLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        CurrentValue  % slider value
        Limits % Description
        xt % Description
        yt % Description
        pxy % Description
        qxy % Description
    end
    
    methods (Access = private)
        
        function UpdateGraph(app)
            t = linspace(app.Limits(1),app.Limits(2),app.CurrentValue);
            t_ = linspace(app.Limits(1),app.Limits(2),app.CurrentValue*100);
            try
                curve_x = app.xt(t);
                curve_x_ = app.xt(t_);
                curve_y = app.yt(t);
                curve_y_ = app.yt(t_);
                
                velocity_x = diff(curve_x);
                velocity_x_ = diff(curve_x_);
                velocity_y = diff(curve_y);
                velocity_y_ = diff(curve_y_);
                
                estimation_velocities = [velocity_x;velocity_y]; 
                exact_velocities = [velocity_x_;velocity_y_]; 
                
                [minx, maxx] = bounds(curve_x_);
                [miny, maxy] = bounds(curve_y_); 
                
                x = linspace(minx,maxx);
                y = linspace(miny,maxy);
                [x,y] = meshgrid(x,y);
                
                p = app.pxy(x,y);
                q = app.qxy(x,y);
                
                estimation_x_components = app.pxy(curve_x(1:end-1),curve_y(1:end-1));
                estimation_x_components_ = app.pxy(curve_x_(1:end-1),curve_y_(1:end-1));
                estimation_y_components = app.qxy(curve_x(1:end-1),curve_y(1:end-1));
                estimation_y_components_ = app.qxy(curve_x_(1:end-1),curve_y_(1:end-1));
                
                estimation_forces = [estimation_x_components;estimation_y_components];
                exact_forces = [estimation_x_components_;estimation_y_components_];
                
                estimation = sum(dot(estimation_forces,estimation_velocities)); 
                exact = sum(dot(exact_forces,exact_velocities));
                
                app.Estimation.Text = sprintf('%0.3f',estimation);
                app.Exact.Text = sprintf('%0.3f',exact);
                app.Error.Text = sprintf('%0.3f',abs(exact - estimation));
                
                xlim(app.UIAxes,[minx maxx]);
                ylim(app.UIAxes,[miny maxy]);
                axis(app.UIAxes,'equal')
                quiver(app.UIAxes,x,y,p,q);
                hold(app.UIAxes,"on")
                plot(app.UIAxes,curve_x,curve_y);
                plot(app.UIAxes,curve_x_,curve_y_);
                hold(app.UIAxes,"off")
                
                app.PxyEditField.BackgroundColor = [1 1 1];
                app.QxyEditField.BackgroundColor = [1 1 1];
                app.XtEditField.BackgroundColor = [1 1 1];
                app.YtEditField.BackgroundColor = [1 1 1];
            catch
                app.PxyEditField.BackgroundColor = [1 .6 .6];
                app.QxyEditField.BackgroundColor = [1 .6 .6];
                app.XtEditField.BackgroundColor = [1 .6 .6];
                app.YtEditField.BackgroundColor = [1 .6 .6];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CurrentValue = ceil(app.PartitionsSlider.Value);
            app.xt = str2func(strcat('@(t)',app.XtEditField.Value));
            app.yt = str2func(strcat('@(t)',app.YtEditField.Value));
            app.pxy = str2func(strcat('@(x,y)',app.PxyEditField.Value));
            app.qxy = str2func(strcat('@(x,y)',app.QxyEditField.Value));
            
            app.Limits = [-pi,pi];
            UpdateGraph(app)
        end

        % Value changing function: PartitionsSlider
        function SliderUpdate(app, event)
            changingValue = ceil(event.Value);
            app.CurrentValue = changingValue;
            UpdateGraph(app)
        end

        % Value changed function: MaxPartitionsEditField
        function MaxChanged(app, event)
            value = app.MaxPartitionsEditField.Value;
            numericVal = str2double(value);
            if isnan(numericVal)
                app.MaxPartitionsEditField.BackgroundColor=[1 .6 .6];
                return
            else
                app.MaxPartitionsEditField.BackgroundColor=[1 1 1];
            end
            app.PartitionsSlider.Limits = [2,numericVal];
            UpdateGraph(app)
        end

        % Value changed function: XtEditField
        function XtEditFieldValueChanged(app, event)
            value = strcat('@(t)',app.XtEditField.Value);
            try
                app.xt = str2func(value);
                UpdateGraph(app);
            catch
                app.XtEditField.BackgroundColor = [1 .6 .6];
            end
        end

        % Value changed function: tLimitsEditField
        function tLimitsEditFieldValueChanged(app, event)
            value = app.tLimitsEditField.Value;
            value((value == ']') | (value =='[')) = '';
            lims = split(value,',');
            lims = str2double(lims);
            if(length(lims) ~= 2 ||...
                any(isnan(lims)) ||...
                abs(max(lims)-min(lims))<.5)
                app.tLimitsEditField.BackgroundColor = [1 .6 .6];
                return
            end
            app.Limits = [min(lims),max(lims)];
            UpdateGraph(app);
        end

        % Value changed function: YtEditField
        function YtEditFieldValueChanged(app, event)
            value = strcat('@(t)',app.YtEditField.Value);
            try
                app.yt = str2func(value);
                UpdateGraph(app);
            catch
                app.YtEditField.BackgroundColor = [1 .6 .6];
            end
        end

        % Value changed function: PxyEditField
        function PxyEditFieldValueChanged(app, event)
            value = strcat('@(x,y)',app.PxyEditField.Value);
            try
                app.pxy = str2func(value);
                UpdateGraph(app);
            catch
                app.PxyEditField.BackgroundColor = [1 .6 .6];
            end
        end

        % Value changed function: QxyEditField
        function QxyEditFieldValueChanged(app, event)
            value = strcat('@(x,y)',app.QxyEditField.Value);
            try
                app.qxy = str2func(value);
                UpdateGraph(app);
            catch
                app.QxyEditField.BackgroundColor = [1 .6 .6];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExploratoryMathematicsUIFigure = uifigure('Visible', 'off');
            app.ExploratoryMathematicsUIFigure.Name = 'Exploratory Mathematics';
            app.TabGroup = uitabgroup(app.ExploratoryMathematicsUIFigure);
            app.LineIntegralVectorFieldsTab = uitab(app.TabGroup);
            app.LineIntegralVectorFieldsTab.Title = 'Line Integral Vector Fields';
            app.UIAxes = uiaxes(app.LineIntegralVectorFieldsTab);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PartitionsSliderLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.PartitionsSliderLabel.Text = 'Partitions:';
            app.PartitionsSlider = uislider(app.LineIntegralVectorFieldsTab);
            app.PartitionsSlider.Limits = [2 100];
            app.PartitionsSlider.ValueChangingFcn = createCallbackFcn(app, @SliderUpdate, true);
            app.PartitionsSlider.Value = 25;
            app.MaxPartitionsEditFieldLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.MaxPartitionsEditFieldLabel.Text = 'Max Partitions:';
            app.MaxPartitionsEditField = uieditfield(app.LineIntegralVectorFieldsTab, 'text');
            app.MaxPartitionsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxChanged, true);
            app.MaxPartitionsEditField.Value = '100';
            app.EstimationLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.EstimationLabel.Text = 'Estimation:';
            app.Estimation = uilabel(app.LineIntegralVectorFieldsTab);
            app.Estimation.Text = 'NaN';
            app.ErrorLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.ErrorLabel.Text = 'Error:';
            app.Error = uilabel(app.LineIntegralVectorFieldsTab);
            app.Error.Text = 'NaN';
            app.BetterEstimationLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.BetterEstimationLabel.Text = 'Better Estimation:';
            app.Exact = uilabel(app.LineIntegralVectorFieldsTab);
            app.Exact.Text = 'NaN';
            app.XtEditFieldLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.XtEditFieldLabel.Text = 'X(t)=';
            app.XtEditField = uieditfield(app.LineIntegralVectorFieldsTab, 'text');
            app.XtEditField.ValueChangedFcn = createCallbackFcn(app, @XtEditFieldValueChanged, true);
            app.XtEditField.Value = 'cos(t)';
            app.tLimitsEditFieldLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.tLimitsEditFieldLabel.Text = 't Limits:';
            app.tLimitsEditField = uieditfield(app.LineIntegralVectorFieldsTab, 'text');
            app.tLimitsEditField.ValueChangedFcn = createCallbackFcn(app, @tLimitsEditFieldValueChanged, true);
            app.tLimitsEditField.Value = '[-3.14,3.14]';
            app.YtEditFieldLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.YtEditFieldLabel.Text = 'Y(t)=';
            app.YtEditField = uieditfield(app.LineIntegralVectorFieldsTab, 'text');
            app.YtEditField.ValueChangedFcn = createCallbackFcn(app, @YtEditFieldValueChanged, true);
            app.YtEditField.Value = 'sin(t)';
            app.PxyLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.PxyLabel.Text = 'P(x,y)=';
            app.PxyEditField = uieditfield(app.LineIntegralVectorFieldsTab, 'text');
            app.PxyEditField.ValueChangedFcn = createCallbackFcn(app, @PxyEditFieldValueChanged, true);
            app.PxyEditField.Value = 'x+y';
            app.QxyEditFieldLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.QxyEditFieldLabel.Text = 'Q(x,y)=';
            app.QxyEditField = uieditfield(app.LineIntegralVectorFieldsTab, 'text');
            app.QxyEditField.ValueChangedFcn = createCallbackFcn(app, @QxyEditFieldValueChanged, true);
            app.QxyEditField.Value = 'y';
            app.FxyPxyiQxyLabel = uilabel(app.LineIntegralVectorFieldsTab);
            app.FxyPxyiQxyLabel.Text = 'F(x,y) = P(x,y) + iQ(x,y)';
            % Show the figure after all components are created
            app.ExploratoryMathematicsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Line_Integral_Vector_Fields

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExploratoryMathematicsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExploratoryMathematicsUIFigure)
        end
    end
end