classdef zadehApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        SupportAleftboundEditFieldLabel  matlab.ui.control.Label
        SupportAleftboundEditField     matlab.ui.control.NumericEditField
        SupportBleftboundEditFieldLabel  matlab.ui.control.Label
        SupportBleftboundEditField     matlab.ui.control.NumericEditField
        MaxSliderLabel                 matlab.ui.control.Label
        MaxSlider                      matlab.ui.control.Slider
        MaxSlider_2Label               matlab.ui.control.Label
        MaxSlider_2                    matlab.ui.control.Slider
        SupportArightboundEditField_2Label  matlab.ui.control.Label
        SupportArightboundEditField_2  matlab.ui.control.NumericEditField
        SupportArightboundEditFieldLabel  matlab.ui.control.Label
        SupportArightboundEditField    matlab.ui.control.NumericEditField
        OperatorDropDownLabel          matlab.ui.control.Label
        OperatorDropDown               matlab.ui.control.DropDown
        solveatpointEditFieldLabel     matlab.ui.control.Label
        solveatpointEditField          matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        suppA;
        suppB;
        equation;
        z; % point of calculations
        invEquation;
    end

    methods (Access = public)
    
        function solveEquation(app)
            A = @(t) trimf(t,app.suppA);
            B = @(t) trimf(subs(app.invEquation,t),app.suppB);
            solveInterval = app.suppA(1):0.01:app.suppA(end);
            logical0Map = solveInterval == 0;
            solveInterval(logical0Map) = [];
            Acalc = A(solveInterval);
            Bcalc = B(solveInterval);
            minAB = min(Acalc,Bcalc);
            plot(app.UIAxes,solveInterval,Acalc,solveInterval,Bcalc,solveInterval,minAB);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function OnCreate(app)
            % initialize supp a
            app.suppA(1) = app.SupportAleftboundEditField.Value;
            app.suppA(3) = app.SupportArightboundEditField_2.Value;
            app.suppA(2) = (app.suppA(1) + app.suppA(3))/2;
            % initialize supp b
            app.suppB(1) = app.SupportBleftboundEditField.Value;
            app.suppB(3) = app.SupportArightboundEditField.Value;
            app.suppB(2) = (app.suppB(1) + app.suppB(3))/2;
            % initialize equation
            syms x y;
            app.equation = x * y - 0; % zero is z's default value
            % initialize z
            app.z = 0; % nothing to commit :)
            % initialize invEquation
            app.invEquation = solve(app.equation == 0,y);
            % initialize app
            solveEquation(app);
        end

        % Value changed function: OperatorDropDown
        function OperatorDropDownValueChanged(app, event)
            selected_index = app.OperatorDropDown.Value;
            syms x y;
            switch(selected_index)
                case('Subtraction')
                    app.equation = x - y - app.z;
                case('Multiplication')
                    app.equation = x * y - app.z;
                case('Division')
                    app.equation = x / y - app.z;
                case('Addition')
                    app.equation = x + y - app.z;
            end
            app.invEquation = solve(app.equation == 0,y);% operator definition
            solveEquation(app);
        end

        % Value changed function: solveatpointEditField
        function solveatpointEditFieldValueChanged(app, event)
            app.z = app.solveatpointEditField.Value;
            syms x y;
            app.equation = x * y - app.z;
            app.invEquation = solve(app.equation == 0,y);
            solveEquation(app);
        end

        % Value changed function: SupportAleftboundEditField
        function SupportAleftboundEditFieldValueChanged(app, event)
            app.suppA(1) = app.SupportAleftboundEditField.Value;
            solveEquation(app);
        end

        % Value changed function: SupportArightboundEditField_2
        function SupportArightboundEditField_2ValueChanged(app, event)
            val = app.SupportArightboundEditField_2.Value;
            if(val ~= app.suppA(1))
                app.suppA(3) = val;
                solveEquation(app);
            else
                warning('support bounds should be different')
            end
        end

        % Value changed function: SupportBleftboundEditField
        function SupportBleftboundEditFieldValueChanged(app, event)
            app.suppB(1) = app.SupportBleftboundEditField.Value;
            solveEquation(app);
        end

        % Value changed function: SupportArightboundEditField
        function SupportArightboundEditFieldValueChanged(app, event)
            val = app.SupportArightboundEditField.Value;
            if(val ~= app.suppB(1))
                app.suppB(3) = val;
                solveEquation(app);
            else
                warning('support bounds should be different')
            end
        end

        % Value changing function: MaxSlider
        function MaxSliderValueChanging(app, event)
            app.suppA(2) = app.suppA(end)*event.Value+(1-event.Value)*app.suppA(1);
            solveEquation(app);
        end

        % Value changing function: MaxSlider_2
        function MaxSlider_2ValueChanging(app, event)
            app.suppB(2) = app.suppB(end)*event.Value+(1-event.Value)*app.suppB(1);
            solveEquation(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.SupportAleftboundEditFieldLabel = uilabel(app.UIFigure);
            app.SupportAleftboundEditFieldLabel.Text = 'Support A left bound';
            app.SupportAleftboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.SupportAleftboundEditField.ValueChangedFcn = createCallbackFcn(app, @SupportAleftboundEditFieldValueChanged, true);
            app.SupportBleftboundEditFieldLabel = uilabel(app.UIFigure);
            app.SupportBleftboundEditFieldLabel.Text = 'Support B left bound';
            app.SupportBleftboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.SupportBleftboundEditField.ValueChangedFcn = createCallbackFcn(app, @SupportBleftboundEditFieldValueChanged, true);
            app.SupportBleftboundEditField.Value = 1;
            app.MaxSliderLabel = uilabel(app.UIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.UIFigure);
            app.MaxSlider.Limits = [0 1];
            app.MaxSlider.ValueChangingFcn = createCallbackFcn(app, @MaxSliderValueChanging, true);
            app.MaxSlider.Value = 0.5;
            app.MaxSlider_2Label = uilabel(app.UIFigure);
            app.MaxSlider_2Label.Text = 'Max';
            app.MaxSlider_2 = uislider(app.UIFigure);
            app.MaxSlider_2.Limits = [0 1];
            app.MaxSlider_2.ValueChangingFcn = createCallbackFcn(app, @MaxSlider_2ValueChanging, true);
            app.MaxSlider_2.Value = 0.5;
            app.SupportArightboundEditField_2Label = uilabel(app.UIFigure);
            app.SupportArightboundEditField_2Label.Text = 'Support A right bound';
            app.SupportArightboundEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SupportArightboundEditField_2.ValueChangedFcn = createCallbackFcn(app, @SupportArightboundEditField_2ValueChanged, true);
            app.SupportArightboundEditField_2.Value = 1;
            app.SupportArightboundEditFieldLabel = uilabel(app.UIFigure);
            app.SupportArightboundEditFieldLabel.Text = 'Support A right bound';
            app.SupportArightboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.SupportArightboundEditField.ValueChangedFcn = createCallbackFcn(app, @SupportArightboundEditFieldValueChanged, true);
            app.SupportArightboundEditField.Value = 2;
            app.OperatorDropDownLabel = uilabel(app.UIFigure);
            app.OperatorDropDownLabel.Text = 'Operator';
            app.OperatorDropDown = uidropdown(app.UIFigure);
            app.OperatorDropDown.Items = {'Multiplication', 'Addition', 'Subtraction', 'Division'};
            app.OperatorDropDown.ValueChangedFcn = createCallbackFcn(app, @OperatorDropDownValueChanged, true);
            app.OperatorDropDown.Value = 'Multiplication';
            app.solveatpointEditFieldLabel = uilabel(app.UIFigure);
            app.solveatpointEditFieldLabel.Text = 'solve at point';
            app.solveatpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.solveatpointEditField.ValueChangedFcn = createCallbackFcn(app, @solveatpointEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = zadehApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @OnCreate)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end